classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        PeakCurrent                 matlab.ui.container.Panel
        computeButton               matlab.ui.control.Button
        FileNameEditField           matlab.ui.control.EditField
        MATLABFilterMILSTD188125CheckerLabel  matlab.ui.control.Label
        PeakCurrent10APeakRateofRise10MAsRootAction016As12Label  matlab.ui.control.Label
        UploadcsvfileLabel          matlab.ui.control.Label
        FileNameLabel               matlab.ui.control.Label
        RightPanel                  matlab.ui.container.Panel
        current                     matlab.ui.control.UIAxes
        diffcurrent                 matlab.ui.control.UIAxes
        PeakCurrentAEditFieldLabel  matlab.ui.control.Label
        PeakCurrentA                matlab.ui.control.NumericEditField
        PeakRateofCurrentMAsEditFieldLabel  matlab.ui.control.Label
        PeakRateofCurrentMAs        matlab.ui.control.NumericEditField
        RootActionAs12Label         matlab.ui.control.Label
        RootAction                  matlab.ui.control.NumericEditField
        CheckBox                    matlab.ui.control.CheckBox
        CheckBox_3                  matlab.ui.control.CheckBox
        CheckBox_2                  matlab.ui.control.CheckBox
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {204, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Button pushed function: computeButton
        function computeButtonPushed(app, event)
            file=app.FileNameEditField.Value;
            %file = uigetfile('*.csv');
            Array=csvread(file,1,0);
%with an offset of 1 row and 0 columns
            x = Array(:, 1);
            y = Array(:, 2);
            plot(app.current,x, y);
            peakcurrent=max(y);
            app.PeakCurrentA.Value = peakcurrent;
            if peakcurrent<=10
                app.CheckBox.Value=1;
            else
                app.CheckBox.Value=0;
            end
            dydx = diff([eps; y(:)])./diff([eps; x(:)]);
            %differentiate with respective x
            plot(app.diffcurrent,x, dydx);
            maxA=max(dydx);
            minA=min(dydx);
            peakA=maxA/1000000;
            app.PeakRateofCurrentMAs.Value = peakA;
            if peakA<=10
                app.CheckBox_2.Value=1;
            else
                app.CheckBox_2.Value=0;
            end
            ysq=y.^2;
            Int=trapz(x,ysq);
            Int=abs(Int);
            Int=sqrt(Int);
            format short g
            app.RootAction.Value = Int;
            if Int<=0.16
                app.CheckBox_3.Value=1;
            else
                app.CheckBox_3.Value=0;
            end


        end

        % Value changed function: FileNameEditField
        function FileNameEditFieldValueChanged(app, event)
         
        end

        % Value changed function: CheckBox
        function CheckBoxValueChanged(app, event)
            
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.PeakCurrent = uipanel(app.GridLayout);
            app.computeButton = uibutton(app.PeakCurrent, 'push');
            app.computeButton.ButtonPushedFcn = createCallbackFcn(app, @computeButtonPushed, true);
            app.computeButton.Text = 'compute!';
            app.FileNameEditField = uieditfield(app.PeakCurrent, 'text');
            app.FileNameEditField.ValueChangedFcn = createCallbackFcn(app, @FileNameEditFieldValueChanged, true);
            app.FileNameEditField.Value = 'C:\Users\vigna\Documents\1.SAMEER\simulation stuff\matlab\data.csv';
            app.MATLABFilterMILSTD188125CheckerLabel = uilabel(app.PeakCurrent);
            app.MATLABFilterMILSTD188125CheckerLabel.Text = {'MATLAB Filter '; 'MIL-STD-188-125'; 'Checker'};
            app.PeakCurrent10APeakRateofRise10MAsRootAction016As12Label = uilabel(app.PeakCurrent);
            app.PeakCurrent10APeakRateofRise10MAsRootAction016As12Label.Text = {'Peak Current<=  10 A'; 'Peak Rate of Rise<= 10MA/s'; 'Root Action<=  0.16A*s^1/2'};
            app.UploadcsvfileLabel = uilabel(app.PeakCurrent);
            app.UploadcsvfileLabel.Text = 'Upload .csv file';
            app.FileNameLabel = uilabel(app.PeakCurrent);
            app.FileNameLabel.Text = 'File Name:';
            app.RightPanel = uipanel(app.GridLayout);
            app.current = uiaxes(app.RightPanel);
            title(app.current, {'I(2ohms)'; ''})
            xlabel(app.current, 'TIme(ms)')
            ylabel(app.current, 'Current(A)')
            app.diffcurrent = uiaxes(app.RightPanel);
            title(app.diffcurrent, 'dI/dt')
            xlabel(app.diffcurrent, 'Time(s)')
            ylabel(app.diffcurrent, 'Rate of current(A/s)')
            app.PeakCurrentAEditFieldLabel = uilabel(app.RightPanel);
            app.PeakCurrentAEditFieldLabel.Text = 'Peak Current (A)';
            app.PeakCurrentA = uieditfield(app.RightPanel, 'numeric');
            app.PeakCurrentA.ValueDisplayFormat = '%.10f';
            app.PeakRateofCurrentMAsEditFieldLabel = uilabel(app.RightPanel);
            app.PeakRateofCurrentMAsEditFieldLabel.Text = 'Peak Rate of Current (MA/s)';
            app.PeakRateofCurrentMAs = uieditfield(app.RightPanel, 'numeric');
            app.PeakRateofCurrentMAs.ValueDisplayFormat = '%.10f';
            app.RootActionAs12Label = uilabel(app.RightPanel);
            app.RootActionAs12Label.Text = 'Root Action (A*s^1/2)';
            app.RootAction = uieditfield(app.RightPanel, 'numeric');
            app.RootAction.ValueDisplayFormat = '%.10f';
            app.CheckBox = uicheckbox(app.RightPanel);
            app.CheckBox.ValueChangedFcn = createCallbackFcn(app, @CheckBoxValueChanged, true);
            app.CheckBox.Text = '';
            app.CheckBox_3 = uicheckbox(app.RightPanel);
            app.CheckBox_3.Text = '';
            app.CheckBox_2 = uicheckbox(app.RightPanel);
            app.CheckBox_2.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end